classdef vision_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout5                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        HistogramFiltersTab             matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        BrowseButton                    matlab.ui.control.Button
        AxesImg                         matlab.ui.control.UIAxes
        AxesHist                        matlab.ui.control.UIAxes
        TabGroup_2                      matlab.ui.container.TabGroup
        HistogramEqTab                  matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        AxesNewImg                      matlab.ui.control.UIAxes
        AxesHistEq                      matlab.ui.control.UIAxes
        FiltersTab                      matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        SobelFilterLabel                matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        DirectionLabel                  matlab.ui.control.Label
        ThresholdSliderLabel            matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        LaplacianFilterLabel            matlab.ui.control.Label
        AlphaSliderLabel                matlab.ui.control.Label
        AlphaSlider                     matlab.ui.control.Slider
        AxesFiltered                    matlab.ui.control.UIAxes
        ViewFourierTransformTab         matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        AxesFrr                         matlab.ui.control.UIAxes
        NoiseTab                        matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        AxesNoisy                       matlab.ui.control.UIAxes
        AxesNRemoved                    matlab.ui.control.UIAxes
        AxesNFrr                        matlab.ui.control.UIAxes
        AxesNRFrr                       matlab.ui.control.UIAxes
        AddRemoveNoisePanel             matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        SaltPepperTab                   matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        NoiseSliderLabel                matlab.ui.control.Label
        NoiseSlider                     matlab.ui.control.Slider
        RemoveSaltpepperLabel           matlab.ui.control.Label
        MedianFilterSizeEditFieldLabel  matlab.ui.control.Label
        MedianFilterSizeEditField       matlab.ui.control.NumericEditField
        PeriodicTab                     matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        WavetypeButtonGroup             matlab.ui.container.ButtonGroup
        SinButton                       matlab.ui.control.RadioButton
        CosButton                       matlab.ui.control.RadioButton
        NxEditFieldLabel                matlab.ui.control.Label
        NxEditField                     matlab.ui.control.NumericEditField
        NyEditFieldLabel                matlab.ui.control.Label
        NyEditField                     matlab.ui.control.NumericEditField
        RemovePeriodicNoiseLabel        matlab.ui.control.Label
        MaskTypeDropDownLabel           matlab.ui.control.Label
        MaskTypeDropDown                matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        imagefile; % the data of the original browsed image
        noisyImage; % noisy image (either s&p or periodic)
        noisyFourier; % fourier of noisy image
        isBrowsed=0; % image selected yet
    end
    
    methods (Access = public)
        
        function fourierImage = fourierTransform(~,Image)
            fourierImage=fftshift(fft2(Image));
%             logged=log(1+abs(fftshifted));
%             fm = max(logged(:));%
%             fourierImage=im2uint8(logged/fm);%
            %fourierImage=mat2gray(logged);
        end
        
        function fftshow(~,Image,Axes)
            logged=log(1+abs(Image));
            fm = max(logged(:));%
            fourierImage=im2uint8(logged/fm);
            imshow(fourierImage,'parent',Axes);
        end
        
        function resetNoise(app)
            app.noisyImage=0;
            app.noisyFourier=0;
            app.isBrowsed=0;
            cla(app.AxesNoisy);
            cla(app.AxesNRemoved);
            cla(app.AxesNFrr);
            cla(app.AxesNRFrr);
            app.disableDenoise();
        end
        
        function disableDenoise(app)
            app.MaskTypeDropDown.Enable=0;
            app.MedianFilterSizeEditField.Enable=0;
        end
        
        function enableDenoise(app)
            app.MaskTypeDropDown.Enable=1;
            app.MedianFilterSizeEditField.Enable=1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           
           app.resetNoise(); %resets graphs for new images
           
           [filename, filepath] =uigetfile({'*.jpg;*.png;*.jpeg;'},'Select File');
           fullpath= strcat(filepath,filename);
           try
               %app.imagefile=imread('cameraman.tif');
               app.imagefile = imread(fullpath);
               if(size(app.imagefile, 3) == 3)
                app.imagefile=rgb2gray(app.imagefile); %converting input image to gray
               end
               
               imshow(app.imagefile,'parent',app.AxesImg);
               axis(app.AxesImg, "on", "tight");
               
               %calculating histogram
               [pixelCount, grayLevels] = imhist(app.imagefile);
               bar(app.AxesHist,grayLevels,pixelCount,"blue");
               
               newImage=histeq(app.imagefile);
               imshow(newImage,'parent',app.AxesNewImg);
               axis(app.AxesNewImg, "on", "tight");
               
               %calculating new histogram
               [pixelCount, grayLevels] = imhist(newImage);
               bar(app.AxesHistEq,grayLevels,pixelCount,"red");
              
               fftImage=app.fourierTransform(im2double(app.imagefile));
               app.fftshow(fftImage,app.AxesFrr);
               
               figure(app.UIFigure); % magical function that makes the window maximized
               app.isBrowsed=1;
               
           catch ME
               warndlg('Not an image or corrupted','Warning');
           end
           
           
        end

        % Value changed function: DropDown, ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            if(app.isBrowsed==1)
                sobelDir=app.DropDown.Value;
                filteredImg=edge(app.imagefile,'sobel', (value)/100,sobelDir);
                imshow(filteredImg,'parent',app.AxesFiltered);
                axis(app.AxesFiltered, "on", "tight");
            else
                warndlg('Browse image first','Warning');
            end
        end

        % Value changed function: AlphaSlider
        function AlphaSliderValueChanged(app, event)
            value = app.AlphaSlider.Value;
            if(app.isBrowsed==1)
                fltr = fspecial('laplacian',value);
                filteredImg=filter2(fltr,app.imagefile,'same');
                %filteredImg=mat2gray(filteredImg);
                imshow(filteredImg/100,'parent',app.AxesFiltered);
                axis(app.AxesFiltered, "on", "tight");
            else
                warndlg('Browse image first','Warning');
            end
        end

        % Callback function: NoiseSlider, NoiseSlider
        function NoiseSliderValueChanged(app, event)
            value = app.NoiseSlider.Value;
            if(app.isBrowsed==1)
                app.noisyImage=imnoise(app.imagefile,'salt & pepper',value/100);
                imshow(app.noisyImage,'parent',app.AxesNoisy);
                axis(app.AxesNoisy, "on", "tight");
                
                NoisyFourier=app.fourierTransform(im2double(app.noisyImage));
                app.fftshow(NoisyFourier,app.AxesNFrr);
                axis(app.AxesNFrr, "on", "tight");
                
                if (value~=0)
                    app.MedianFilterSizeEditField.Enable=1;
                else
                    app.MedianFilterSizeEditField.Enable=0;
                end
                app.MedianFilterSizeEditFieldValueChanged();
            else
                warndlg('Browse image first','Warning');
            end
        end

        % Callback function: NxEditField, NyEditField, 
        % WavetypeButtonGroup
        function WavetypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.WavetypeButtonGroup.SelectedObject;
            if(app.isBrowsed==1)
                s = size(app.imagefile);
                [x, y]= meshgrid(1:s(2),1:s(1));
                nx = app.NxEditField.Value; % number of cycles
                ny = app.NyEditField.Value;
                Wx = max(max(x)); %Length of signals 
                Wy = max(max(y));
                fx = nx/Wx;
                fy = ny/Wy;
                if(selectedButton==app.SinButton)
                    pxy = sin(2*pi*fx*x + 2*pi*fy*y)+1; 
                else
                    pxy = cos(2*pi*fx*x + 2*pi*fy*y)+1;
                end
                app.noisyImage = mat2gray((im2double(app.imagefile)+pxy));
                app.noisyFourier=app.fourierTransform(app.noisyImage);
                imshow(app.noisyImage,'parent',app.AxesNoisy);
                axis(app.AxesNoisy, "on", "tight");
                app.fftshow(app.noisyFourier,app.AxesNFrr);
                axis(app.AxesNFrr, "on", "tight");
                
                if(nx==0 && ny==0)
                    app.MaskTypeDropDown.Enable=0;  %grey out removal of noise if there is no noise
                else
                    app.MaskTypeDropDown.Enable=1;
                end
                app.MaskTypeDropDownValueChanged();
            else
                warndlg('Browse image first','Warning');
            end
        end

        % Value changed function: MaskTypeDropDown
        function MaskTypeDropDownValueChanged(app, event)
            value = app.MaskTypeDropDown.Value;
            s = size(app.imagefile);
            [x, y]= meshgrid(-s(2)/2:(s(2)/2)-1,-s(1)/2:(s(1)/2)-1); %creating a matrix of distances according to image size
            nx=app.NxEditField.Value;
            ny=app.NyEditField.Value;
            if(strcmp(value,'Band Reject'))
                z=sqrt(x.^2+y.^2);
                spikeDist=sqrt(nx^2 + ny^2); %calculate distance of spikes
                br=(z < spikeDist-1 | z > spikeDist+1); % reject band of frequencies around spikes
                if(nx==0 && ny==0)
                    bandFourier=im2double(app.noisyFourier);
                else
                    bandFourier=im2double(app.noisyFourier).*br;
                end
                cleanImg=ifft2(ifftshift(bandFourier));
                app.fftshow(bandFourier,app.AxesNRFrr);
                imshow(mat2gray(abs(cleanImg)),'parent',app.AxesNRemoved);
                axis(app.AxesNRemoved, "on", "tight");
            elseif(strcmp(value,'Notch'))
                notchFourier=im2double(app.noisyFourier);
                %% removes BOTH row and columns ONLY if both are not zeros, or else it will also remove central frequency
                if(ny~=0)
                    notchFourier((s(1)/2)+ny+1,:)=0;
                    notchFourier((s(1)/2)-ny+1,:)=0;
                end
                
                if(nx~=0)
                    notchFourier(:,(s(2)/2)+nx+1)=0; 
                    notchFourier(:,(s(2)/2)-nx+1)=0;
                end
                
                cleanImg=ifft2(ifftshift(notchFourier));
                app.fftshow(notchFourier,app.AxesNRFrr);
                imshow(mat2gray(abs(cleanImg)),'parent',app.AxesNRemoved);
                axis(app.AxesNRemoved, "on", "tight");
            elseif(strcmp(value,'Mask'))
                
                pixelRange = 10 ; % range of pixels around spike for zooming
                app.AxesNFrr.YLim = [(s(1)/2)+1+(ny - pixelRange),(s(1)/2)+1+(ny + pixelRange)]; % makes First point in the center and waits for click
                app.AxesNFrr.XLim = [(s(2)/2)+1+(nx - pixelRange),(s(2)/2)+1+(nx + pixelRange)];
                try
                    ROI = drawpoint(app.AxesNFrr);
                    point1=round(ROI.Position);
                    delete(ROI);
                catch ME
                    return
                end
                
                
                app.AxesNFrr.YLim = [(s(1)/2)+1-ny - pixelRange,(s(1)/2)+1-ny + pixelRange]; % makes Second point in the center and waits for click
                app.AxesNFrr.XLim = [(s(2)/2)+1-nx - pixelRange,(s(2)/2)+1-nx + pixelRange];
                
                try
                ROI = drawpoint(app.AxesNFrr);
                point2=round(ROI.Position);
                delete(ROI);
                catch ME
                    return
                end
                mask = ones(size(app.noisyFourier));
                mask(point1(2),point1(1)) = 0;
                mask(point2(2),point2(1)) = 0;
                filtered = mask.*app.noisyFourier;
                
                app.fftshow(filtered,app.AxesNRFrr);
                cleanImg=ifft2(ifftshift(filtered));
                imshow(mat2gray(abs(cleanImg)),'parent',app.AxesNRemoved);
                axis(app.AxesNFrr, "on", "tight");
                
                % I dont reset the cleaned fourier view on purpose, so we
                % can see band-reject, Notch and Mask effects clearly
            end
        end

        % Value changed function: MedianFilterSizeEditField
        function MedianFilterSizeEditFieldValueChanged(app, event)
            value = app.MedianFilterSizeEditField.Value;
            cleanImg=medfilt2(app.noisyImage,[value,value]);
            cleanImgfft=app.fourierTransform(im2double(cleanImg));
            app.fftshow(cleanImgfft,app.AxesNRFrr);
            imshow(mat2gray(cleanImg),'parent',app.AxesNRemoved);
            axis(app.AxesNRemoved, "on", "tight");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout5 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout5);
            app.HistogramFiltersTab = uitab(app.TabGroup);
            app.HistogramFiltersTab.Title = 'Histogram & Filters';
            app.GridLayout = uigridlayout(app.HistogramFiltersTab);
            app.BrowseButton = uibutton(app.GridLayout, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.AxesImg = uiaxes(app.GridLayout);
            title(app.AxesImg, 'Image')
            xlabel(app.AxesImg, '')
            ylabel(app.AxesImg, '')
            app.AxesImg.XTick = [];
            app.AxesImg.YTick = [];
            app.AxesHist = uiaxes(app.GridLayout);
            title(app.AxesHist, 'Histogram')
            xlabel(app.AxesHist, 'X')
            ylabel(app.AxesHist, 'Y')
            app.TabGroup_2 = uitabgroup(app.GridLayout);
            app.HistogramEqTab = uitab(app.TabGroup_2);
            app.HistogramEqTab.Title = 'Histogram Eq';
            app.GridLayout2 = uigridlayout(app.HistogramEqTab);
            app.AxesNewImg = uiaxes(app.GridLayout2);
            title(app.AxesNewImg, 'New Image')
            xlabel(app.AxesNewImg, '')
            ylabel(app.AxesNewImg, '')
            app.AxesNewImg.XTick = [];
            app.AxesNewImg.YTick = [];
            app.AxesHistEq = uiaxes(app.GridLayout2);
            title(app.AxesHistEq, 'Equalized Histogram')
            xlabel(app.AxesHistEq, 'X')
            ylabel(app.AxesHistEq, 'Y')
            app.FiltersTab = uitab(app.TabGroup_2);
            app.FiltersTab.Title = 'Filters';
            app.GridLayout4 = uigridlayout(app.FiltersTab);
            app.SobelFilterLabel = uilabel(app.GridLayout4);
            app.SobelFilterLabel.Text = 'Sobel Filter';
            app.DropDown = uidropdown(app.GridLayout4);
            app.DropDown.Items = {'horizontal', 'vertical', 'both'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.DropDown.Value = 'horizontal';
            app.DirectionLabel = uilabel(app.GridLayout4);
            app.DirectionLabel.Text = 'Direction';
            app.ThresholdSliderLabel = uilabel(app.GridLayout4);
            app.ThresholdSliderLabel.Text = 'Threshold %';
            app.ThresholdSlider = uislider(app.GridLayout4);
            app.ThresholdSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.LaplacianFilterLabel = uilabel(app.GridLayout4);
            app.LaplacianFilterLabel.Text = 'Laplacian Filter';
            app.AlphaSliderLabel = uilabel(app.GridLayout4);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.GridLayout4);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaSliderValueChanged, true);
            app.AlphaSlider.Value = 0.2;
            app.AxesFiltered = uiaxes(app.GridLayout4);
            title(app.AxesFiltered, 'Filtered Image')
            xlabel(app.AxesFiltered, '')
            ylabel(app.AxesFiltered, '')
            app.AxesFiltered.XTick = [];
            app.AxesFiltered.YTick = [];
            app.ViewFourierTransformTab = uitab(app.TabGroup_2);
            app.ViewFourierTransformTab.Title = 'View Fourier Transform';
            app.GridLayout3 = uigridlayout(app.ViewFourierTransformTab);
            app.AxesFrr = uiaxes(app.GridLayout3);
            title(app.AxesFrr, 'Fourier Transform ')
            xlabel(app.AxesFrr, '')
            ylabel(app.AxesFrr, '')
            app.AxesFrr.XTick = [];
            app.AxesFrr.YTick = [];
            app.NoiseTab = uitab(app.TabGroup);
            app.NoiseTab.Title = 'Noise';
            app.GridLayout6 = uigridlayout(app.NoiseTab);
            app.AxesNoisy = uiaxes(app.GridLayout6);
            title(app.AxesNoisy, 'Noisy Image')
            xlabel(app.AxesNoisy, '')
            ylabel(app.AxesNoisy, '')
            app.AxesNoisy.XTick = [];
            app.AxesNoisy.YTick = [];
            app.AxesNRemoved = uiaxes(app.GridLayout6);
            title(app.AxesNRemoved, 'Cleaned Image')
            xlabel(app.AxesNRemoved, '')
            ylabel(app.AxesNRemoved, '')
            app.AxesNRemoved.XTick = [];
            app.AxesNRemoved.YTick = [];
            app.AxesNFrr = uiaxes(app.GridLayout6);
            title(app.AxesNFrr, 'Noisy Fourier')
            xlabel(app.AxesNFrr, '')
            ylabel(app.AxesNFrr, '')
            app.AxesNFrr.XTick = [];
            app.AxesNFrr.YTick = [];
            app.AxesNRFrr = uiaxes(app.GridLayout6);
            title(app.AxesNRFrr, 'Cleaned Fourier')
            xlabel(app.AxesNRFrr, '')
            ylabel(app.AxesNRFrr, '')
            app.AxesNRFrr.XTick = [];
            app.AxesNRFrr.YTick = [];
            app.AddRemoveNoisePanel = uipanel(app.GridLayout6);
            app.AddRemoveNoisePanel.Title = 'Add & Remove Noise';
            app.GridLayout7 = uigridlayout(app.AddRemoveNoisePanel);
            app.TabGroup2 = uitabgroup(app.GridLayout7);
            app.SaltPepperTab = uitab(app.TabGroup2);
            app.SaltPepperTab.Title = 'Salt &  Pepper';
            app.GridLayout8 = uigridlayout(app.SaltPepperTab);
            app.NoiseSliderLabel = uilabel(app.GridLayout8);
            app.NoiseSliderLabel.Text = 'Noise %';
            app.NoiseSlider = uislider(app.GridLayout8);
            app.NoiseSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseSliderValueChanged, true);
            app.NoiseSlider.ValueChangingFcn = createCallbackFcn(app, @NoiseSliderValueChanged, true);
            app.RemoveSaltpepperLabel = uilabel(app.GridLayout8);
            app.RemoveSaltpepperLabel.Text = 'Remove Salt & pepper';
            app.MedianFilterSizeEditFieldLabel = uilabel(app.GridLayout8);
            app.MedianFilterSizeEditFieldLabel.Text = 'Median Filter Size';
            app.MedianFilterSizeEditField = uieditfield(app.GridLayout8, 'numeric');
            app.MedianFilterSizeEditField.Limits = [3 256];
            app.MedianFilterSizeEditField.RoundFractionalValues = 'on';
            app.MedianFilterSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MedianFilterSizeEditFieldValueChanged, true);
            app.MedianFilterSizeEditField.Enable = 'off';
            app.MedianFilterSizeEditField.Value = 3;
            app.PeriodicTab = uitab(app.TabGroup2);
            app.PeriodicTab.Title = 'Periodic';
            app.GridLayout9 = uigridlayout(app.PeriodicTab);
            app.WavetypeButtonGroup = uibuttongroup(app.GridLayout9);
            app.WavetypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WavetypeButtonGroupSelectionChanged, true);
            app.WavetypeButtonGroup.Title = 'Wave type';
            app.SinButton = uiradiobutton(app.WavetypeButtonGroup);
            app.SinButton.Text = 'Sin';
            app.SinButton.Value = true;
            app.CosButton = uiradiobutton(app.WavetypeButtonGroup);
            app.CosButton.Text = 'Cos';
            app.NxEditFieldLabel = uilabel(app.GridLayout9);
            app.NxEditFieldLabel.Text = 'Nx';
            app.NxEditField = uieditfield(app.GridLayout9, 'numeric');
            app.NxEditField.Limits = [0 128];
            app.NxEditField.ValueChangedFcn = createCallbackFcn(app, @WavetypeButtonGroupSelectionChanged, true);
            app.NyEditFieldLabel = uilabel(app.GridLayout9);
            app.NyEditFieldLabel.Text = 'Ny';
            app.NyEditField = uieditfield(app.GridLayout9, 'numeric');
            app.NyEditField.Limits = [0 128];
            app.NyEditField.ValueChangedFcn = createCallbackFcn(app, @WavetypeButtonGroupSelectionChanged, true);
            app.RemovePeriodicNoiseLabel = uilabel(app.GridLayout9);
            app.RemovePeriodicNoiseLabel.Text = 'Remove Periodic Noise';
            app.MaskTypeDropDownLabel = uilabel(app.GridLayout9);
            app.MaskTypeDropDownLabel.Text = 'Mask Type';
            app.MaskTypeDropDown = uidropdown(app.GridLayout9);
            app.MaskTypeDropDown.Items = {'Band Reject', 'Notch', 'Mask'};
            app.MaskTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MaskTypeDropDownValueChanged, true);
            app.MaskTypeDropDown.Enable = 'off';
            app.MaskTypeDropDown.Value = 'Band Reject';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vision_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end